classdef Badminton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChoosewheretoserveonthecourtPanel  matlab.ui.container.Panel
        XDestinationmEditFieldLabel     matlab.ui.control.Label
        XDestinationmEditField          matlab.ui.control.NumericEditField
        ZStartingpositionmEditFieldLabel  matlab.ui.control.Label
        ZStartingpositionmEditField     matlab.ui.control.NumericEditField
        ZDestinationmEditFieldLabel     matlab.ui.control.Label
        ZDestinationmEditField          matlab.ui.control.NumericEditField
        ElevationAngleLabel             matlab.ui.control.Label
        ElevationAngleEditField         matlab.ui.control.NumericEditField
        ServeAngleEditFieldLabel        matlab.ui.control.Label
        ServeAngleEditField             matlab.ui.control.NumericEditField
        ServeButton                     matlab.ui.control.Button
        InputsLabel                     matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        ImpactVelocitymsEditFieldLabel  matlab.ui.control.Label
        ImpactVelocitymsEditField       matlab.ui.control.NumericEditField
        ClearCourtButton                matlab.ui.control.Button
        RandomiseButton                 matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        z05; %starting serve position in Z direction
        destX; %where to serve to in X direction
        destZ; %where to serve to in Z direction
        theta01; %elevation angle
        theta02; %serve angle
        ImpVel; %Impact Velocity
        dt=0.001;%time step
        t0=0;%start time
        tend=2;
        t; %end time
        z; %state vector
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %shows a plot of the badminton court when the app is started
            % Badminton Court Diagram
            %Right doubles side line
            SLDoublesX = linspace(6.7,-6.7); %Doubles side line length in x
            RightSLDoublesZ = linspace(3.05,3.05); %Doubles side line length in z
            onTheFloor = zeros(1,100); %no height to the court
            plot3(app.UIAxes,SLDoublesX,RightSLDoublesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            hold (app.UIAxes,'on')
            xlim(app.UIAxes,[-7 7])
            ylim(app.UIAxes,[-4 4])
            zlim(app.UIAxes, [0 2.5])
            xlabel(app.UIAxes,'X (m)')
            ylabel(app.UIAxes,'Z (m)')
            zlabel(app.UIAxes,'Y (m)')
            grid(app.UIAxes,'on')
            rotate3d(app.UIAxes,'on')
            
            %left doubles sideline
            LeftSLDoublesZ = linspace(-3.05,-3.05);
            plot3(app.UIAxes,SLDoublesX,LeftSLDoublesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %back long singles service line
            ServeSinglesZ = linspace(3.05,-3.05);
            BLServeSinglesX = linspace(-6.7,-6.7);
            plot3(app.UIAxes,BLServeSinglesX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %front long singles service line
            FLServeSinglesX = linspace(6.7,6.7);
            plot3(app.UIAxes,FLServeSinglesX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %back long doubles service line
            BLServeDoublesX = linspace(-5.92,-5.92);
            plot3(app.UIAxes,BLServeDoublesX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %front long doubles service line
            FLServeDoublesX = linspace(5.92,5.92);
            plot3(app.UIAxes,FLServeDoublesX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %back short service line
            BSServeLineX = linspace (-2.00,-2.00);
            plot3(app.UIAxes,BSServeLineX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %front short sapp.UIAxes,ervice line
            FSServeLineX = linspace (2.00,2.00);
            plot3(app.UIAxes,FSServeLineX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %net line
            NetFloorLine = linspace (0,0);
            plot3(app.UIAxes,NetFloorLine,ServeSinglesZ,onTheFloor,'--','Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %%left singles sideline
            LeftSLSinglesZ = linspace(-2.57,-2.57);
            plot3(app.UIAxes,SLDoublesX,LeftSLSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %%right singles sideline
            RightSLSinglesZ = linspace(2.57,2.57);
            plot3(app.UIAxes,SLDoublesX,RightSLSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %back centre line
            BCentreLineX = linspace(-2.00,-6.70);
            CentreLineZ = linspace (0,0);
            plot3(app.UIAxes,BCentreLineX,CentreLineZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %front centre line
            FCentreLineX = linspace(2.00,6.70);
            plot3(app.UIAxes,FCentreLineX,CentreLineZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %% Net Dimensions
            
            %Left Post
            PostX = linspace (0,0);
            LeftPostZ = linspace(-3.05,-3.05);
            PostY = linspace(0,1.55);
            plot3(app.UIAxes,PostX,LeftPostZ,PostY,'k','LineWidth',1)
            
            %Right Post
            RightPostZ = linspace(3.05,3.05);
            plot3(app.UIAxes,PostX,RightPostZ,PostY,'k','LineWidth',1)
            
            %top line of the net modelled as an exponential curve
            NetTopX =linspace (0,0);
            NetZ = ServeSinglesZ;
            NetY = 1.524 + 0.002794947*(NetZ).^2;
            plot3(app.UIAxes,NetTopX,NetZ,NetY,'k','LineWidth',1)
            
            % lower top line of net
            LTNetY = 1.474 + 0.002794947*(NetZ).^2;
            plot3(app.UIAxes,NetTopX,NetZ,LTNetY,'k','LineWidth',1)
            
            % bottom of net
            BNetY = 0.974 + 0.002794947*(NetZ).^2;
            plot3(app.UIAxes,NetTopX,NetZ,BNetY,'k','LineWidth',1)
        end

        % Button pushed function: ServeButton
        function ServeButtonPushed(app, event)
            %Serves the shuttlecock when the SERVE button is pressed
            app.z05 = app.ZStartingpositionmEditField.Value; %takes z start position user input
            app.destX = app.XDestinationmEditField.Value; % x destination user input
            app.destZ = app.ZDestinationmEditField.Value;% z destiantion user input
            
            [app.theta01,app.theta02,app.ImpVel]=ShootingXYZ(app.z05,[app.destX,app.destZ]); % finds the desired angles and impact velocity
            
            app.ElevationAngleEditField.Value = app.theta01; %displays the elevation angle
            app.ServeAngleEditField.Value = app.theta02; % displays the serve angle
            app.ImpactVelocitymsEditField.Value = app.ImpVel; %displays the Impact velocity
            
            %plots the path of the shuttlecock
            app.theta01 = app.theta01*(pi/180);
            app.theta02 = app.theta02*(pi/180);
           [app.t,app.z]=ivpSolverXYZ(app.t0,[-2.1;75*cos(app.theta01)*cos(app.theta02);1;75*sin(app.theta01)*cos(app.theta02);app.z05;75*sin(app.theta02)*cos(app.theta01)],app.dt,app.tend);
            plot3(app.UIAxes,app.z(1,:),app.z(5,:),app.z(3,:),'LineWidth',0.75)
            hold (app.UIAxes,'on')
            zlim(app.UIAxes, [0 1+Inf])

            
        end

        % Button pushed function: ClearCourtButton
        function ClearCourtButtonPushed(app, event)
            %clears the court of shuttle paths and replaces it with the empty badminton court
            hold (app.UIAxes,'off')
            % Badminton Court Diagram
            %Right doubles side line
            SLDoublesX = linspace(6.7,-6.7); %Doubles side line length in x
            RightSLDoublesZ = linspace(3.05,3.05); %Doubles side line length in z
            onTheFloor = zeros(1,100); %no height to the court
            plot3(app.UIAxes,SLDoublesX,RightSLDoublesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            hold (app.UIAxes,'on')
            xlim(app.UIAxes,[-7 7])
            ylim(app.UIAxes,[-4 4])
            zlim(app.UIAxes, [0 2.5])
            xlabel(app.UIAxes,'X (m)')
            ylabel(app.UIAxes,'Z (m)')
            zlabel(app.UIAxes,'Y (m)')
            grid(app.UIAxes,'on')
            rotate3d(app.UIAxes,'on')
            
            %left doubles sideline
            LeftSLDoublesZ = linspace(-3.05,-3.05);
            plot3(app.UIAxes,SLDoublesX,LeftSLDoublesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %back long singles service line
            ServeSinglesZ = linspace(3.05,-3.05);
            BLServeSinglesX = linspace(-6.7,-6.7);
            plot3(app.UIAxes,BLServeSinglesX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %front long singles service line
            FLServeSinglesX = linspace(6.7,6.7);
            plot3(app.UIAxes,FLServeSinglesX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %back long doubles service line
            BLServeDoublesX = linspace(-5.92,-5.92);
            plot3(app.UIAxes,BLServeDoublesX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %front long doubles service line
            FLServeDoublesX = linspace(5.92,5.92);
            plot3(app.UIAxes,FLServeDoublesX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %back short service line
            BSServeLineX = linspace (-2.00,-2.00);
            plot3(app.UIAxes,BSServeLineX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %front short sapp.UIAxes,ervice line
            FSServeLineX = linspace (2.00,2.00);
            plot3(app.UIAxes,FSServeLineX,ServeSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %net line
            NetFloorLine = linspace (0,0);
            plot3(app.UIAxes,NetFloorLine,ServeSinglesZ,onTheFloor,'--','Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %%left singles sideline
            LeftSLSinglesZ = linspace(-2.57,-2.57);
            plot3(app.UIAxes,SLDoublesX,LeftSLSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %%right singles sideline
            RightSLSinglesZ = linspace(2.57,2.57);
            plot3(app.UIAxes,SLDoublesX,RightSLSinglesZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %back centre line
            BCentreLineX = linspace(-2.00,-6.70);
            CentreLineZ = linspace (0,0);
            plot3(app.UIAxes,BCentreLineX,CentreLineZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            %front centre line
            FCentreLineX = linspace(2.00,6.70);
            plot3(app.UIAxes,FCentreLineX,CentreLineZ,onTheFloor,'Color',[0.8 0.5 0.2],'LineWidth',1)
            
            
            %% Net Dimensions
            
            %Left Post
            PostX = linspace (0,0);
            LeftPostZ = linspace(-3.05,-3.05);
            PostY = linspace(0,1.55);
            plot3(app.UIAxes,PostX,LeftPostZ,PostY,'k','LineWidth',1)
            
            %Right Post
            RightPostZ = linspace(3.05,3.05);
            plot3(app.UIAxes,PostX,RightPostZ,PostY,'k','LineWidth',1)
            
            %top line of the net modelled as an exponential curve
            NetTopX =linspace (0,0);
            NetZ = ServeSinglesZ;
            NetY = 1.524 + 0.002794947*(NetZ).^2;
            plot3(app.UIAxes,NetTopX,NetZ,NetY,'k','LineWidth',1)
            
            % lower top line of net
            LTNetY = 1.474 + 0.002794947*(NetZ).^2;
            plot3(app.UIAxes,NetTopX,NetZ,LTNetY,'k','LineWidth',1)
            
            % bottom of net
            BNetY = 0.974 + 0.002794947*(NetZ).^2;
            plot3(app.UIAxes,NetTopX,NetZ,BNetY,'k','LineWidth',1)
            hold(app.UIAxes,'on')
        end

        % Value changed function: RandomiseButton
        function RandomiseButtonValueChanged(app, event)
            %RANDOMISE button creates random inputs 
            Random = rand(1,3);
            
            app.ZStartingpositionmEditField.Value = Random(1,1)*(0.5);
            app.XDestinationmEditField.Value = Random(1,2)*(0.37)+1.98;
            app.ZDestinationmEditField.Value = Random(1,3)*(-0.5);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChoosewheretoserveonthecourtPanel = uipanel(app.UIFigure);
            app.ChoosewheretoserveonthecourtPanel.Title = 'Choose where to serve on the court.';
            app.XDestinationmEditFieldLabel = uilabel(app.ChoosewheretoserveonthecourtPanel);
            app.XDestinationmEditFieldLabel.Text = 'X Destination (m)';
            app.XDestinationmEditField = uieditfield(app.ChoosewheretoserveonthecourtPanel, 'numeric');
            app.XDestinationmEditField.Limits = [1.98 2.35];
            app.XDestinationmEditField.Value = 2.1;
            app.ZStartingpositionmEditFieldLabel = uilabel(app.ChoosewheretoserveonthecourtPanel);
            app.ZStartingpositionmEditFieldLabel.Text = 'Z Starting position (m)';
            app.ZStartingpositionmEditField = uieditfield(app.ChoosewheretoserveonthecourtPanel, 'numeric');
            app.ZStartingpositionmEditField.Limits = [0 0.5];
            app.ZStartingpositionmEditField.Value = 0.1;
            app.ZDestinationmEditFieldLabel = uilabel(app.ChoosewheretoserveonthecourtPanel);
            app.ZDestinationmEditFieldLabel.Text = 'Z Destination (m)';
            app.ZDestinationmEditField = uieditfield(app.ChoosewheretoserveonthecourtPanel, 'numeric');
            app.ZDestinationmEditField.Limits = [-0.5 0];
            app.ZDestinationmEditField.Value = -0.1;
            app.ElevationAngleLabel = uilabel(app.ChoosewheretoserveonthecourtPanel);
            app.ElevationAngleLabel.Text = 'Elevation Angle (°)';
            app.ElevationAngleEditField = uieditfield(app.ChoosewheretoserveonthecourtPanel, 'numeric');
            app.ElevationAngleEditField.Editable = 'off';
            app.ServeAngleEditFieldLabel = uilabel(app.ChoosewheretoserveonthecourtPanel);
            app.ServeAngleEditFieldLabel.Text = 'Serve Angle (°)';
            app.ServeAngleEditField = uieditfield(app.ChoosewheretoserveonthecourtPanel, 'numeric');
            app.ServeAngleEditField.Editable = 'off';
            app.ServeButton = uibutton(app.ChoosewheretoserveonthecourtPanel, 'push');
            app.ServeButton.ButtonPushedFcn = createCallbackFcn(app, @ServeButtonPushed, true);
            app.ServeButton.Text = 'Serve';
            app.InputsLabel = uilabel(app.ChoosewheretoserveonthecourtPanel);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.ChoosewheretoserveonthecourtPanel);
            app.OutputsLabel.Text = 'Outputs';
            app.ImpactVelocitymsEditFieldLabel = uilabel(app.ChoosewheretoserveonthecourtPanel);
            app.ImpactVelocitymsEditFieldLabel.Text = 'Impact Velocity (m/s)';
            app.ImpactVelocitymsEditField = uieditfield(app.ChoosewheretoserveonthecourtPanel, 'numeric');
            app.ImpactVelocitymsEditField.Editable = 'off';
            app.ClearCourtButton = uibutton(app.ChoosewheretoserveonthecourtPanel, 'push');
            app.ClearCourtButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCourtButtonPushed, true);
            app.ClearCourtButton.Text = 'Clear Court';
            app.RandomiseButton = uibutton(app.ChoosewheretoserveonthecourtPanel, 'state');
            app.RandomiseButton.ValueChangedFcn = createCallbackFcn(app, @RandomiseButtonValueChanged, true);
            app.RandomiseButton.Text = 'Randomise';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pan Around the Court')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Badminton

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end